classdef OLGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        saveModel                     matlab.ui.container.Menu
        loadModel                     matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        VideoReEncodeTab              matlab.ui.container.Tab
        doneLabel                     matlab.ui.control.Label
        outputFolder                  matlab.ui.control.Label
        inputFolder                   matlab.ui.control.Label
        reencodeButton                matlab.ui.control.Button
        pickOutput                    matlab.ui.control.Button
        pickInput                     matlab.ui.control.Button
        encodeInfo                    matlab.ui.control.Label
        DLCAnalysisTab                matlab.ui.container.Tab
        analysisProgressLabel         matlab.ui.control.Label
        performAnalysis               matlab.ui.control.Button
        AnalysisSettingsPanel         matlab.ui.container.Panel
        frontCalcs                    matlab.ui.control.NumericEditField
        CalculationsEditField_4Label  matlab.ui.control.Label
        backCalcs                     matlab.ui.control.NumericEditField
        CalculationsEditField_3Label  matlab.ui.control.Label
        headCalcs                     matlab.ui.control.NumericEditField
        CalculationsEditField_2Label  matlab.ui.control.Label
        wingCalcs                     matlab.ui.control.NumericEditField
        CalculationsEditFieldLabel    matlab.ui.control.Label
        FrontLegspathLabel            matlab.ui.control.Label
        useFrontLegsCheck             matlab.ui.control.CheckBox
        choosePath_4                  matlab.ui.control.Button
        choosePath_3                  matlab.ui.control.Button
        BackLegspathLabel             matlab.ui.control.Label
        useBackLegsCheck              matlab.ui.control.CheckBox
        HeadpathLabel                 matlab.ui.control.Label
        useHeadCheck                  matlab.ui.control.CheckBox
        choosePath_2                  matlab.ui.control.Button
        WingspathLabel                matlab.ui.control.Label
        choosePath_1                  matlab.ui.control.Button
        useWingsCheck                 matlab.ui.control.CheckBox
        CompilingDataTab              matlab.ui.container.Tab
    end

    
    properties (Access = private)
        % These should contain the input and output folder locations for
        % the re-encode step of OL experiments
        reencodeInputFolder
        reencodeOutputFolder

        % All useful model options to store
            % Set model paths to null on launch
            headPath = 'N/A';
            wingPath = 'N/A';
            frontPath = 'N/A';
            backPath = 'N/A';

            % Set user model choices to 0 on launch
            useWings = 0;
            useHead = 0; 
            useFront = 0;
            useBack = 0;
    end
    
    methods (Access = private)
        
        function disableAnalysis(app)
            if ~app.useHeadCheck.Value && ~app.useWingsCheck.Value && ...
            ~app.useFrontLegsCheck.Value && ~app.useBackLegsCheck.Value
                app.performAnalysis.Enable = "off";
            end
        end

        function modelList = updateModelList(~, modelList, keyword, filePath, noOfLabels, labels, calculations)
            if ~isempty(modelList) % Append to model list if not first model added
                position = length(modelList) + 1;
                modelList(position).keyword = keyword;
                modelList(position).filePath = convertStringsToChars(filePath);
                modelList(position).nooflabels = noOfLabels; %number of body parts being tracked by this model.
                modelList(position).labels = labels;
                modelList(position).calculations = calculations; %Number of variables being calculated : WBA Right and Left, + confidence for each, + LongitudinalAxis (Theta_Angle) and its confidence
            else % If this is the first entry to the model list, initialise it
                modelList(1).keyword = keyword;
                modelList(1).filePath = convertStringsToChars(filePath);
                modelList(1).nooflabels = noOfLabels; %number of body parts being tracked by this model.
                modelList(1).labels = labels;
                modelList(1).calculations = calculations; %Number of variables being calculated : WBA Right and Left, + confidence for each, + LongitudinalAxis (Theta_Angle) and its confidence                
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Name the app 
            app.UIFigure.Name = "Open Loop Steps";
        end

        % Button pushed function: pickInput
        function pickInputButtonPushed(app, event)
            % Ask user to locate the inputFolderPath where the videos they wish to encode should be located 
            app.reencodeInputFolder = uigetdir('/home/hoverfly/Documents/', 'Location of Videos to encode');
            app.inputFolder.Text = "Input Folder: " + app.reencodeInputFolder;
            if ~isempty(app.reencodeInputFolder) && ~isempty(app.reencodeOutputFolder)
                app.reencodeButton.Enable = "on";
            end

        end

        % Button pushed function: pickOutput
        function pickOutputButtonPushed(app, event)
            % Ask user to locate the outputFilePath where they wish the Re-encoded versions to be stored
            app.reencodeOutputFolder = uigetdir('/home/hoverfly/Documents/', 'Location to store Re-encoded videos');
            app.outputFolder.Text = "Output Folder: " + app.reencodeOutputFolder;
            if ~isempty(app.reencodeInputFolder) && ~isempty(app.reencodeOutputFolder)
                app.reencodeButton.Enable = "on";
            end
        end

        % Button pushed function: reencodeButton
        function reencodeButtonPushed(app, event)
            % Hide doneLabel, if this is not first run of re-encode
            app.doneLabel.Enable = 'Off';

            % Call the re-encode function 
            reencodeFcn(app.reencodeInputFolder, app.reencodeOutputFolder)

            % Show doneLabel, visual feedback for user
            app.doneLabel.Enable = 'On';
        end

        % Button pushed function: choosePath_1
        function choosePath_1ButtonPushed(app, event)
            [file, path] = uigetfile(strcat('', '*.yaml'));
            filePath = fullfile(path, file);
            if filePath ~= 0 %cancel returns 0
                app.wingPath = filePath;
                app.WingspathLabel.Text = "Wings path: " + filePath;
            end
        end

        % Button pushed function: choosePath_2
        function choosePath_2ButtonPushed(app, event)
            [file, path] = uigetfile(strcat('', '*.yaml'));
            filePath = fullfile(path, file);
            if filePath ~= 0 %cancel returns 0
                app.headPath = filePath;
                app.HeadpathLabel.Text = "Head path: " + filePath;
            end
        end

        % Button pushed function: choosePath_3
        function choosePath_3ButtonPushed(app, event)
            [file, path] = uigetfile(strcat('', '*.yaml')); %Grab the needed config file
            filePath = fullfile(path, file);
            if filePath ~= 0 %cancel returns 0
                app.backPath = filePath;
                app.BackLegspathLabel.Text = "BackLegs path: " + filePath;
            end
        end

        % Button pushed function: choosePath_4
        function choosePath_4ButtonPushed(app, event)
            [file, path] = uigetfile(strcat('', '*.yaml'));
            filePath = fullfile(path, file);
            if filePath ~= 0 %cancel returns 0
                app.frontPath = filePath;
                app.FrontLegspathLabel.Text = "FrontLegs path: " + filePath;
            end         
        end

        % Value changed function: useWingsCheck
        function useWingsCheckValueChanged(app, event)
            modelSettings = getappdata(0, 'modelSettings');
            value = app.useWingsCheck.Value;
            if value == 1
                app.choosePath_1.Enable = "on";
                app.performAnalysis.Enable = "on";
            else
                app.choosePath_1.Enable = "off";
                disableAnalysis(app)
            end
            modelSettings.useWings = value;
            setappdata(0, 'modelSettings', modelSettings);
        end

        % Value changed function: useHeadCheck
        function useHeadCheckValueChanged(app, event)
            modelSettings = getappdata(0, 'modelSettings');
            value = app.useHeadCheck.Value;
            if value == 1
                app.choosePath_2.Enable = "on";
                app.performAnalysis.Enable = "on";
                
            else
                app.choosePath_2.Enable = "off";
                disableAnalysis(app)
            end      
            modelSettings.useHead = value;
            setappdata(0, 'modelSettings', modelSettings);
        end

        % Value changed function: useBackLegsCheck
        function useBackLegsCheckValueChanged(app, event)
            modelSettings = getappdata(0, 'modelSettings');
            value = app.useBackLegsCheck.Value;
            if value == 1
                app.choosePath_3.Enable = "on";
                app.performAnalysis.Enable = "on";
            else
                app.choosePath_3.Enable = "off";
                disableAnalysis(app)
            end            
            modelSettings.useBack = value;
            setappdata(0, 'modelSettings', modelSettings);
        end

        % Value changed function: useFrontLegsCheck
        function useFrontLegsCheckValueChanged(app, event)
            modelSettings = getappdata(0, 'modelSettings');
            value = app.useFrontLegsCheck.Value;
            if value == 1
                app.choosePath_4.Enable = "on";
                app.performAnalysis.Enable = "on";
            else
                app.choosePath_4.Enable = "off";
                disableAnalysis(app)
            end           
            modelSettings.useFront = value;
            setappdata(0, 'modelSettings', modelSettings);
        end

        % Button pushed function: performAnalysis
        function performAnalysisButtonPushed(app, event)
            % Hard Coded Variables (must be accurate)
            AnacondaEnvironment = 'DLC2';       % Name of the Anaconda Environment that houses the version of DLC we wish to use.
            VideoType = '.mp4';                 % Used to identify video files
            MaximumSubFolderDepth = 8;          % Safety to stop this script grabbing too many video files
            MaximumFolderCount = 500;           % Safety to stop this script grabbing too many video files
            IpythoncommandsFilePath = '/home/hoverfly/Desktop/MatlabScripts/OpenLoop/Ipythoncommands.py'; % This script is essential for DLC Analysis to run

            %Skippause can be used to toggle pauses on or off allowing you to more easily follow along with the output.
            Skippause = 1; % 0 for false (pause) : 1 for true (don't pause)

            % Model Information (must be accurate)
            %A list of the DLC models each video should be analysed by
            %keyword refers to a Unique ID that can be used to identify each model's .csv files
            modelSettings = getappdata(0, 'modelSettings');

            modelList = [];

            if app.useWingsCheck.Value
                keyword = 'Wings';
                filePath = modelSettings.wingPath;
                noOfLabels = 6; %number of body parts being tracked by this model.
                labels = ['Wings_Hinge_Right,','Wings_Distal_Right,','Wings_Hinge_Left,','Wings_Distal_Left,','Wings_Thorax_Upper,','Wings_Thorax_Lower'];
                calculations = 6; %Number of variables being calculated : WBA Right and Left, + confidence for each, + LongitudinalAxis (Theta_Angle) and its confidence
                modelList = updateModelList(app, modelList, keyword, filePath, noOfLabels, labels, calculations);
            end

            if app.useHeadCheck.Value
                keyword = 'Head';
                filePath = modelSettings.headPath;
                noOfLabels = 6; %number of body parts being tracked by this model.
                labels = ['Head_Midline_Anterior_1,','Head_Midline_2,','Head_Midline_3,','Head_Midline_4,','Head_Midline_5,','Head_Midline_Posterior_1'];
                calculations = 2; %Number of variables being calculated : Slope + confidence
                modelList = updateModelList(app, modelList, keyword, filePath, noOfLabels, labels, calculations);
            end

            if app.useBackLegsCheck.Value
                keyword = 'BackLegs';
                filePath = modelSettings.backPath;
                noOfLabels = 8; %number of body parts being tracked by this model.
                labels = ['Hindlegs_Proximal_Right,','Hindlegs_Knee_Right,','Hindlegs_Distal_Right,','Hindlegs_Abdomen_Right,','Hindlegs_Proximal_Left,','Hindlegs_Knee_Left,','Hindlegs_Distal_Left,','Hindlegs_Abdomen_Left'];
                calculations = 16; %Number of variables being calculated : ProximalKnee Angle and Distance, KneeDistal Angle and Distance, + confidence for each, Right and Left
                modelList = updateModelList(app, modelList, keyword, filePath, noOfLabels, labels, calculations);
            end

            if app.useFrontLegsCheck.Value
                keyword = 'FrontLegs';
                filePath = modelSettings.frontPath;
                noOfLabels = 4; %number of body parts being tracked by this model.
                labels = ['Frontlegs_Hinge_Right,','Frontlegs_Distal_Right,','Frontlegs_Hinge_Left,','Frontlegs_Distal_Left'];
                calculations = 8; %Number of variables being calculated : Hinge-Distal Angle and Distance, + confidence, Right and Left
                modelList = updateModelList(app, modelList, keyword, filePath, noOfLabels, labels, calculations);
            end

            % Variables
            videoList = [];
            
            %Get size of ModelList (we are only interested in Y value)
            [~, modelListSize] = size(modelList);
            Totalnumberofcalculations = 0;
            Totalnumberofrawdatapoints = 0;
            
            if ~isfile(IpythoncommandsFilePath)
                warning('IpythoncommandsFilePath is not valid')
                return;
            end
            
            for i = 1:modelListSize
                if ~isfile(modelList(i).filePath)
                    warning('%s model does not have a valid filepath',modelList(i).keyword)
                    return;
                end
            
                %Let the user know about the expected data points and their order
                fprintf('This script expects the %s model to have %i labels, identically named and ordered as follows\n',modelList(i).keyword,modelList(i).nooflabels);
                disp(strrep(modelList(i).labels,',',', '));
                disp(' ');
                
                %Get the total number of calculations that will be required
                Totalnumberofcalculations = Totalnumberofcalculations + modelList(i).calculations;
                Totalnumberofrawdatapoints = Totalnumberofrawdatapoints + (modelList(i).nooflabels * 3); %each label has a value for (x,y,confidence)
                
                %Get a list of all the labels
                if i == 1
                    DLC_Field_Names = modelList(1).labels;
                else
                    DLC_Field_Names = strcat(DLC_Field_Names,',',modelList(i).labels);
                end 
                pause(1);
            end
            
            if(Skippause == 0)
                pause(3)
            end
            
            % Ask user to locate the inputFolderPath where the videos they wish to analyse should be located
            inputFolderPath = uigetdir('/home/hoverfly/Desktop/', 'Location of Videos to analyse');
            
            % Check to make sure the file path is valid before continuing
            if(length(inputFolderPath) <= 1)
                disp('Chosen file path is invalid');
                return;
            end
            
            Temp = strsplit(inputFolderPath,'/');
            
            %Add only the Initial Directory to list
            DirectoryList = dir(fullfile(inputFolderPath));
            DirectoryList = DirectoryList(2);
            DirectoryList(1).name = Temp{end};
            DirectoryList(1).filepath = inputFolderPath;
            
            %Set initial DirectoryCount
            PrevDirectoryCount = 0;
            
            %Find all valid Folders upto the MaximumSubFolderDepth
            for i = 1:MaximumSubFolderDepth
                
                %Set NewDirectoryCount which is the current number of valid Directories
                NewDirectoryCount = size(DirectoryList,1);
                
                %Safety Check to make sure we don't accidently add every folder on the system by selecting a folder that is too high up the hierarchy.
                if(NewDirectoryCount > MaximumFolderCount)
                    warning('Warning - Tried to search %i folders when the Maximum is %i. If you selected the right folder, remove this check or increase MaximumFolderCount',NewDirectoryCount,MaximumFolderCount);
                    return;
                end
                
                %For each of the new directories we have not already searched
                for j = PrevDirectoryCount+1:NewDirectoryCount
                    %Locate valid directories within the provided folder and add them to the DirectoryList.
                    DirectoryList = [DirectoryList;FindSubFolders(DirectoryList(j).filepath,0)];
                end
                
                %Update PrevDirectoryCount so we know which folders have been searched.
                PrevDirectoryCount = NewDirectoryCount;  
                %Move on to next loop in order to search the new folders that were added this loop
            end
            
            app.analysisProgressLabel.Text = sprintf('Total number of directories being searched is %i\n',size(DirectoryList,1));
            app.analysisProgressLabel.Visible = "on";

            %Search each folder and locate all valid video files.
            %For each valid folder provided
            for i = 1:size(DirectoryList)
                
                %Find all files within the folder
                TempFileList = dir(fullfile(DirectoryList(i).filepath));
                
                %For each file remembering to skip the first two as they are . ..
                for j = 3:size(TempFileList)
                    
                    %If the file contains a valid video type e.g. filename contains .mp4
                    if not(isempty(strfind(TempFileList(j).name,VideoType)))
                        
                        %If the video is not a labeled video e.g. filename contains labeled
                        if isempty(strfind(TempFileList(j).name,'labeled'))
                            
                            %Add folderpath to structure
                            TempFileList(j).folderpath = DirectoryList(i).filepath;
                            
                            %Add filepath to structure
                            TempFileList(j).filepath = strcat(DirectoryList(i).filepath,'/',TempFileList(j).name);
                            
                            %Add to VideoList
                            videoList = [videoList;TempFileList(j)];
            
                        end
                    end
                end
            
                %Search next folder in the list
            
            end
            
            app.analysisProgressLabel.Text = sprintf('Total number of videos being processed is %i\n',size(videoList,1));
            
            if size(videoList) < 1
                warning('Warning - There are no videos to analyse');
                return;
            end
            
            %In order to pass several commands to the same (terminal / shell) we need to collate them into a single string.
            AnalysisCommand = ['source /home/hoverfly/anaconda3/bin/activate' ' ' AnacondaEnvironment]; %conda activate DLC2 equivalent (for some reason "conda activate DLC2" doesn't work in this context so we use this alternative)
            AnalysisCommand = strcat(AnalysisCommand,';ipython'); % Add the start of the next command 
            AnalysisCommand = [AnalysisCommand ' ' '''' IpythoncommandsFilePath '''']; % Add a space and then the path to python file encapsulated in '
            AnalysisCommand = [AnalysisCommand ' ' '''']; % Adds a space and begins encapsulation of the argument being passed to the python file in ' as '''' converts to a single '
           
            app.analysisProgressLabel.Text = sprintf('0 out of %i videos have been analyzed by deeplabcut\n', (size(videoList,1)));

            for i = 1:size(videoList)
                 
                TempVideoPath = videoList(i).filepath;
                
                for j = 1:modelListSize
                    
                    TempModelPath = modelList(j).filePath;    
                    %Create the command deeplabcut required to analyze a video
                    TempCommand = ['deeplabcut.analyze_videos(',34,TempModelPath,34,',[',34,TempVideoPath,34,'],save_as_csv=True)'];
            
                    %Passes through command responsible for Analysing each video 
                    unix(strcat(AnalysisCommand,TempCommand,''''),'-echo'); % concatenate analysis command and temp command then encapsulation the argument in '
            
                end 
            
                progress = sprintf('%i out of %i videos have been analyzed by deeplabcut\n',i,(size(videoList,1)));

                disp('---------');
                disp(progress);
                disp('---------');

                app.analysisProgressLabel.Text = progress;
            end
            
            if Skippause == 0
                pause(2); %This is here only so you can see how many videos are about to be analysed
            end
            
            if Skippause == 0
                pause(1);
            end        

            % Get path to folder where this step is located
            pathName = which('OLStep2');
            pathName = split(pathName, 'OLStep2');
            pathName = pathName{1};

            % Create filename
            fileName = "analysisPaths_" + string(datetime('now')) + ".mat";
            filePath = append(pathName, fileName);

            %Save required variables for OLStep 3
            save(filePath, "videoList", "modelList", "modelListSize", ...
                "Totalnumberofcalculations", "Totalnumberofrawdatapoints")

            app.analysisProgressLabel.Text = ...
                sprintf('Analysis complete, file required for OLStep3 saved as %s', fileName);
        end

        % Menu selected function: saveModel
        function saveModelMenuSelected(app, event)
            % Ask user for file location
            % uiputfile(string): string may be pathname (c:/folder/*.mat)
            [fileName,pathName,~] = uiputfile(strcat('', '*.mat'));
            
            if fileName % If press on cancel no fileName will be given
                % Save re-encode folder locations
                appSettings.reencodeInputFolder = app.reencodeInputFolder;
                appSettings.reencodeOutputFolder = app.reencodeOutputFolder;

                % Save model caluclation choices
                appSettings.frontCalcs = app.frontCalcs.Value;
                appSettings.backCalcs = app.backCalcs.Value;
                appSettings.headCalcs = app.headCalcs.Value;
                appSettings.wingCalcs = app.wingCalcs.Value;

                % Save model paths and choices
                appSettings.headPath = app.headPath;
                appSettings.wingPath = app.wingPath;
                appSettings.frontPath = app.frontPath;
                appSettings.backPath = app.backPath;

                appSettings.useWings = app.useWings;
                appSettings.useHead = app.useHead; 
                appSettings.useFront = app.useFront;
                appSettings.useBack = app.useBack;

                % Store location of where file was saved
                appSettings.fileName = fileName;
                appSettings.pathName = pathName;
                appSettings.saved    = 1;
                
                save(strcat(pathName, fileName), 'appSettings');
            end
        end

        % Menu selected function: loadModel
        function loadModelMenuSelected(app, event)
            %remember to check if not saved!
            %uigetfile(string): string may be pathname (c:/folder/*.mat)
            [fileName,pathName] = uigetfile(strcat('', '*.mat'));
            
            if fileName   % FileName == false if canceled
                
                load(strcat(pathName, fileName), 'appSettings');
                
                if find(ismember(loadedFiles, 'modelSettings') == 1) %everything ok
                    % Set / update OL step 1 variables
                    app.reencodeInputFolder = appSettings.reencodeInputFolder;
                    app.reencodeOutputFolder = appSettings.reencodeOutputFolder;
                    
                    % Set / update OL step 2 variables
                    app.performAnalysis.Enable = "off";

                    % Update check boxes with saved variables
                    app.useBackLegsCheck.Value = appSettings.useBack;
                    if appSettings.useBack == 1
                        app.choosePath_1.Enable = "on";
                        app.performAnalysis.Enable = "on";
                    else
                        app.choosePath_1.Enable = "off";
                    end

                    app.useFrontLegsCheck.Value = appSettings.useFront;
                    if appSettings.useBack == 1
                        app.choosePath_1.Enable = "on";
                        app.performAnalysis.Enable = "on";
                    else
                        app.choosePath_1.Enable = "off";
                    end

                    app.useHeadCheck.Value = appSettings.useHead;
                    if appSettings.useBack == 1
                        app.choosePath_1.Enable = "on";
                        app.performAnalysis.Enable = "on";
                    else
                        app.choosePath_1.Enable = "off";
                    end

                    app.useWingsCheck.Value = appSettings.useWings;
                    if appSettings.useBack == 1
                        app.choosePath_1.Enable = "on";
                        app.performAnalysis.Enable = "on";
                    else
                        app.choosePath_1.Enable = "off";
                    end

                    % Set amount of calculations for each model
                    app.frontCalcs.Value = appSettings.frontCalcs;
                    app.backCalcs.Value = appSettings.backCalcs;
                    app.headCalcs.Value = appSettings.headCalcs;
                    app.wingCalcs.Value = appSettings.wingCalcs;

                    % Add model paths to environment
                    app.headPath = appSettings.headPath;
                    app.wingPath = appSettings.wingPath;
                    app.frontPath = appSettings.frontPath;
                    app.backPath = appSettings.backPath;

                    % Update path names to be correct
                    app.WingspathLabel.Text = "Wings path: " + appSettings.wingPath;
                    app.HeadpathLabel.Text = "Head path: " + appSettings.headPath;
                    app.BackLegspathLabel.Text = "BackLegs path: " + appSettings.backPath;
                    app.FrontLegspathLabel.Text = "FrontLegs path: " + appSettings.frontPath;
                else
                    disp('incorrect file');
                end
                
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close(gcf)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.saveModel = uimenu(app.FileMenu);
            app.saveModel.MenuSelectedFcn = createCallbackFcn(app, @saveModelMenuSelected, true);
            app.saveModel.Text = 'Save Model';
            app.loadModel = uimenu(app.FileMenu);
            app.loadModel.MenuSelectedFcn = createCallbackFcn(app, @loadModelMenuSelected, true);
            app.loadModel.Text = 'Load Model';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VideoReEncodeTab = uitab(app.TabGroup);
            app.VideoReEncodeTab.Title = 'Video Re-Encode';
            app.encodeInfo = uilabel(app.VideoReEncodeTab);
            app.encodeInfo.Text = 'This script is used to re-encode the video files produced by Guvcview to work properly with Deeplabcut';
            app.pickInput = uibutton(app.VideoReEncodeTab, 'push');
            app.pickInput.ButtonPushedFcn = createCallbackFcn(app, @pickInputButtonPushed, true);
            app.pickInput.Text = 'Select input folder for videos';
            app.pickOutput = uibutton(app.VideoReEncodeTab, 'push');
            app.pickOutput.ButtonPushedFcn = createCallbackFcn(app, @pickOutputButtonPushed, true);
            app.pickOutput.Text = 'Select output folder for re-encoded videos';
            app.reencodeButton = uibutton(app.VideoReEncodeTab, 'push');
            app.reencodeButton.ButtonPushedFcn = createCallbackFcn(app, @reencodeButtonPushed, true);
            app.reencodeButton.Enable = 'off';
            app.reencodeButton.Text = 'Re-encode Videos';
            app.inputFolder = uilabel(app.VideoReEncodeTab);
            app.inputFolder.Text = 'Input Folder: N/A';
            app.outputFolder = uilabel(app.VideoReEncodeTab);
            app.outputFolder.Text = 'Output Folder: N/A';
            app.doneLabel = uilabel(app.VideoReEncodeTab);
            app.doneLabel.Visible = 'off';
            app.doneLabel.Text = 'Re-encoding of videos is complete! :D';
            app.DLCAnalysisTab = uitab(app.TabGroup);
            app.DLCAnalysisTab.Title = 'DLC Analysis';
            app.AnalysisSettingsPanel = uipanel(app.DLCAnalysisTab);
            app.AnalysisSettingsPanel.Title = 'Analysis Settings';
            app.useWingsCheck = uicheckbox(app.AnalysisSettingsPanel);
            app.useWingsCheck.ValueChangedFcn = createCallbackFcn(app, @useWingsCheckValueChanged, true);
            app.useWingsCheck.Text = 'Use ''Wings'' model';
            app.choosePath_1 = uibutton(app.AnalysisSettingsPanel, 'push');
            app.choosePath_1.ButtonPushedFcn = createCallbackFcn(app, @choosePath_1ButtonPushed, true);
            app.choosePath_1.Enable = 'off';
            app.choosePath_1.Text = 'Choose model path';
            app.WingspathLabel = uilabel(app.AnalysisSettingsPanel);
            app.WingspathLabel.Text = 'Wings path: N/A';
            app.choosePath_2 = uibutton(app.AnalysisSettingsPanel, 'push');
            app.choosePath_2.ButtonPushedFcn = createCallbackFcn(app, @choosePath_2ButtonPushed, true);
            app.choosePath_2.Enable = 'off';
            app.choosePath_2.Text = 'Choose model path';
            app.useHeadCheck = uicheckbox(app.AnalysisSettingsPanel);
            app.useHeadCheck.ValueChangedFcn = createCallbackFcn(app, @useHeadCheckValueChanged, true);
            app.useHeadCheck.Text = 'Use ''Head'' model';
            app.HeadpathLabel = uilabel(app.AnalysisSettingsPanel);
            app.HeadpathLabel.Text = 'Head path: N/A';
            app.useBackLegsCheck = uicheckbox(app.AnalysisSettingsPanel);
            app.useBackLegsCheck.ValueChangedFcn = createCallbackFcn(app, @useBackLegsCheckValueChanged, true);
            app.useBackLegsCheck.Text = 'Use ''BackLegs'' model';
            app.BackLegspathLabel = uilabel(app.AnalysisSettingsPanel);
            app.BackLegspathLabel.Text = 'BackLegs path: N/A';
            app.choosePath_3 = uibutton(app.AnalysisSettingsPanel, 'push');
            app.choosePath_3.ButtonPushedFcn = createCallbackFcn(app, @choosePath_3ButtonPushed, true);
            app.choosePath_3.Enable = 'off';
            app.choosePath_3.Text = 'Choose model path';
            app.choosePath_4 = uibutton(app.AnalysisSettingsPanel, 'push');
            app.choosePath_4.ButtonPushedFcn = createCallbackFcn(app, @choosePath_4ButtonPushed, true);
            app.choosePath_4.Enable = 'off';
            app.choosePath_4.Text = 'Choose model path';
            app.useFrontLegsCheck = uicheckbox(app.AnalysisSettingsPanel);
            app.useFrontLegsCheck.ValueChangedFcn = createCallbackFcn(app, @useFrontLegsCheckValueChanged, true);
            app.useFrontLegsCheck.Text = 'Use ''FrontLegs'' model';
            app.FrontLegspathLabel = uilabel(app.AnalysisSettingsPanel);
            app.FrontLegspathLabel.Text = 'FrontLegs path: N/A';
            app.CalculationsEditFieldLabel = uilabel(app.AnalysisSettingsPanel);
            app.CalculationsEditFieldLabel.Text = 'Calculations';
            app.wingCalcs = uieditfield(app.AnalysisSettingsPanel, 'numeric');
            app.wingCalcs.Value = 6;
            app.CalculationsEditField_2Label = uilabel(app.AnalysisSettingsPanel);
            app.CalculationsEditField_2Label.Text = 'Calculations';
            app.headCalcs = uieditfield(app.AnalysisSettingsPanel, 'numeric');
            app.headCalcs.Value = 2;
            app.CalculationsEditField_3Label = uilabel(app.AnalysisSettingsPanel);
            app.CalculationsEditField_3Label.Text = 'Calculations';
            app.backCalcs = uieditfield(app.AnalysisSettingsPanel, 'numeric');
            app.CalculationsEditField_4Label = uilabel(app.AnalysisSettingsPanel);
            app.CalculationsEditField_4Label.Text = 'Calculations';
            app.frontCalcs = uieditfield(app.AnalysisSettingsPanel, 'numeric');
            app.performAnalysis = uibutton(app.DLCAnalysisTab, 'push');
            app.performAnalysis.ButtonPushedFcn = createCallbackFcn(app, @performAnalysisButtonPushed, true);
            app.performAnalysis.Enable = 'off';
            app.performAnalysis.Text = 'Perform Deep Lab Cut Analysis';
            app.analysisProgressLabel = uilabel(app.DLCAnalysisTab);
            app.analysisProgressLabel.Visible = 'off';
            app.analysisProgressLabel.Text = 'Progress...';
            app.CompilingDataTab = uitab(app.TabGroup);
            app.CompilingDataTab.Title = 'Compiling Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OLGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end